classdef HCRprocess < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        MainTab                  matlab.ui.container.Tab
        SettingsTable            matlab.ui.control.Table
        FilesTable               matlab.ui.control.Table
        DirTree                  matlab.ui.container.Tree
        ExperimentDirectoryNode  matlab.ui.container.TreeNode
        StitchingTab             matlab.ui.container.Tab
        StitchTable              matlab.ui.control.Table
        StitchButton             matlab.ui.control.Button
        StitchAllButton          matlab.ui.control.Button
        RegTab                   matlab.ui.container.Tab
        FiducialsButton          matlab.ui.control.Button
        RegTable                 matlab.ui.control.Table
        FiducialsTable           matlab.ui.control.Table
        UnmixTab                 matlab.ui.container.Tab
    end

    
    properties (Access = public)

        exp_dir                            % experiment directory
        ignore_kw = ["ROI", ... 
            "overview", ...
            "TileConfiguration"];          % keywords that mark file to be ignored

        stitch_sel                         % selected row in stitch table
        fiducials_sel                      % selected row in fiducials table

        round_folder                       % currently selected round folder
        T1                                 % table 1 (files table)
        T2                                 % table 2 (settings table)

    end
    
    methods (Access = private)
        
        % populate internal folders, only needs to run once on startup
        function pop_int_folders(app, listing)
            for i=1:length(listing)
                roundf = listing{i};
                check_internal = fullfile(app.exp_dir,roundf,'internal');
                check_build_dir(check_internal)
                check_stitch = fullfile(app.exp_dir,roundf,'stitch');
                check_build_dir(check_stitch)
                check_reg = fullfile(app.exp_dir,roundf,'reg');
                check_build_dir(check_reg)
                check_unmix = fullfile(app.exp_dir,roundf,'unmix');
                check_build_dir(check_unmix)
            end

        end
        
        function update_round(app)

            listing = fetch_dir(fullfile(app.exp_dir,app.round_folder, ...
                'internal'), 'file');

            % inherit pre-existing status tables
            if any(strcmp(listing,'T1.txt'))
                app.T1 = read_table_with_row_names(fullfile(app.exp_dir,app.round_folder,'internal','T1.txt'));
                app.T2 = read_table_with_row_names(fullfile(app.exp_dir,app.round_folder,'internal','T2.txt'));

            % make new ones
            else
                listing = fetch_dir(fullfile(app.exp_dir,app.round_folder,'raw'), 'file');
                listing = filt_kw(listing, app.ignore_kw);
                
                % make sure there are raw files in this round folder
                if length(listing) < 1
                    app.T1 = [];
                    app.T2 = [];
                    update_tables(app)
                    msgbox("No raw files or status tables found in this round folder")
                    return
                end

                nfiles = length(listing);
                for i=1:nfiles
                    split_listing = split(listing{i},'__');
                    listing{i} =  split_listing{1};
                end
                
                app.T1 = table(zeros(nfiles,1), ...
                    zeros(nfiles,1), ...
                    zeros(nfiles,1), ...
                    zeros(nfiles,1), ...
                    'VariableNames',{'Stitched' 'Registered' 'Unmixed' 'Masked'}, ...
                    'RowNames',listing);

                app.T2 = table(zeros(nfiles,1), ...
                    zeros(nfiles,1), ...
                    'VariableNames',{'Fiducials' 'Unmix Settings'}, ...
                    'RowNames',listing);

                write_tables(app)
  
            end

            update_tables(app)

        end

        function update_tables(app)

            app.FilesTable.Data = app.T1;
            app.SettingsTable.Data = app.T2;
            app.StitchTable.Data = app.T1(:,'Stitched');
            app.RegTable.Data = app.T1(:,'Registered');
            app.FiducialsTable.Data = app.T2(:,'Fiducials');
            
            row_names = app.T1.Properties.RowNames;
            app.FilesTable.RowName = row_names;
            app.SettingsTable.RowName = row_names;
            app.StitchTable.RowName = row_names;
            app.RegTable.RowName = row_names;
            app.FiducialsTable.RowName = row_names;
           
        end

        function write_tables(app)

            writetable(app.T1, fullfile(app.exp_dir,app.round_folder,'internal','T1.txt'), ...
                    'WriteRowNames',true);
            writetable(app.T2, fullfile(app.exp_dir,app.round_folder,'internal','T2.txt'), ...
                    'WriteRowNames',true);

        end
        
        % build the full file name of an image given its unique identifier
        % and subfolder
        function img_f = build_img_f(app, img, subfolder)

            listing = fetch_dir(fullfile(app.exp_dir,app.round_folder,subfolder),'file');
            listing = filt_kw(listing, app.ignore_kw);
            full_img = listing{contains(listing, img)};
            img_f = fullfile(app.exp_dir,app.round_folder,subfolder,full_img);
           
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            % set up paths
            [filepath,~,~] = fileparts(mfilename('fullpath'));
            cd(filepath)
            addpath(['./' 'utils'])
            addpath('C:\Users\jpv88\Fiji.app\scripts')
    
            % set experiment directory
            app.exp_dir = uigetdir();
            app.ExperimentDirectoryNode.Text = app.exp_dir;
        
            % populate subfolders (rounds)
            listing = fetch_dir(app.exp_dir, 'dir');
            for i=1:length(listing)
                node = uitreenode(app.ExperimentDirectoryNode,'Text',listing{i}); %#ok<NASGU>
            end

            % populate internal folders
            pop_int_folders(app, listing);
            
            % expand experiment directory and select first round (r0)
            expand(app.ExperimentDirectoryNode)
            app.DirTree.SelectedNodes = app.ExperimentDirectoryNode.Children(1);
            app.round_folder = app.DirTree.SelectedNodes(1).Text;
            update_round(app);
            
        end

        % Selection changed function: DirTree
        function DirTreeSelectionChanged(app, event)
            selectedNodes = app.DirTree.SelectedNodes;
            app.round_folder = selectedNodes(1).Text;
            update_round(app);
        end

        % Cell selection callback: StitchTable
        function StitchTableCellSelection(app, event)
            indices = event.Indices;
            row_idx = indices(1);
            app.stitch_sel = app.StitchTable.Data.Properties.RowNames{row_idx};
        end

        % Button pushed function: StitchButton
        function StitchButtonPushed(app, event)
            img_f = build_img_f(app, app.stitch_sel, 'raw');
            stitch_sel_idx = find(strcmp(app.StitchTable.Data.Properties.RowNames, app.stitch_sel));
            if app.StitchTable.Data(stitch_sel_idx,:).Stitched == 1
                msgbox("This image has already been stitched")
                return
            else
                d = uiprogressdlg(app.UIFigure,'Title','Stitching Progress',...
                    'Message','(0/1 files) Stitching...');
                d.Value = 0;
                file_frac = '(0/1 files)';
                stitch(img_f, d, file_frac)

                app.T1(stitch_sel_idx,:).Stitched = 1;
                update_tables(app)
                write_tables(app)

                d.Value = 1;
                close(d)
            end
        end

        % Button pushed function: StitchAllButton
        function StitchAllButtonPushed(app, event)
            to_stitch = ~app.StitchTable.Data.Stitched;
            if sum(to_stitch) < 1
                msgbox("All images have already been stitched")
                return
            end

            stitch_sels = app.StitchTable.Data.Properties.RowNames(to_stitch);
            
            max_files = sum(to_stitch);
            file_frac = strcat('(0/',string(max_files),' files)');
            d = uiprogressdlg(app.UIFigure,'Title','Stitching Progress',...
                    'Message',strcat(file_frac,' Stitching...'));
            d.Value = 0;
            
            
            for i=1:length(stitch_sels)

                file_frac = strcat('(',string(i-1),'/',string(max_files),' files)');
                d.Message = strcat(file_frac,' Stitching...');

                img_f = build_img_f(app, stitch_sels(i), 'raw');
                stitch_sel_idx = find(strcmp(app.StitchTable.Data.Properties.RowNames, stitch_sels(i)));
                
                stitch(img_f, d, file_frac)

                app.T1(stitch_sel_idx,:).Stitched = 1;
                update_tables(app)
                write_tables(app)

                d.Value = i/max_files;
                
            end

        end

        % Cell selection callback: FiducialsTable
        function FiducialsTableCellSelection(app, event)
            indices = event.Indices;
            row_idx = indices(1);
            app.fiducials_sel = app.FiducialsTable.Data.Properties.RowNames{row_idx};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.DirTree = uitree(app.MainTab);
            app.DirTree.SelectionChangedFcn = createCallbackFcn(app, @DirTreeSelectionChanged, true);
            app.ExperimentDirectoryNode = uitreenode(app.DirTree);
            app.ExperimentDirectoryNode.Text = 'Experiment Directory';
            app.FilesTable = uitable(app.MainTab);
            app.FilesTable.ColumnName = {'Stitched'; 'Registered'; 'Unmixed'; 'Masked'};
            app.FilesTable.RowName = {};
            app.SettingsTable = uitable(app.MainTab);
            app.SettingsTable.ColumnName = {'Fiducials'; 'Unmix Settings'};
            app.SettingsTable.RowName = {};
            app.StitchingTab = uitab(app.TabGroup);
            app.StitchingTab.Title = 'Stitching';
            app.StitchAllButton = uibutton(app.StitchingTab, 'push');
            app.StitchAllButton.ButtonPushedFcn = createCallbackFcn(app, @StitchAllButtonPushed, true);
            app.StitchAllButton.Text = 'Stitch all files';
            app.StitchButton = uibutton(app.StitchingTab, 'push');
            app.StitchButton.ButtonPushedFcn = createCallbackFcn(app, @StitchButtonPushed, true);
            app.StitchButton.Text = 'Stitch this file';
            app.StitchTable = uitable(app.StitchingTab);
            app.StitchTable.ColumnName = {'Stitched'};
            app.StitchTable.RowName = {};
            app.StitchTable.CellSelectionCallback = createCallbackFcn(app, @StitchTableCellSelection, true);
            app.RegTab = uitab(app.TabGroup);
            app.RegTab.Title = 'Registration';
            app.FiducialsTable = uitable(app.RegTab);
            app.FiducialsTable.ColumnName = {'Fiducials'};
            app.FiducialsTable.RowName = {};
            app.FiducialsTable.CellSelectionCallback = createCallbackFcn(app, @FiducialsTableCellSelection, true);
            app.RegTable = uitable(app.RegTab);
            app.RegTable.ColumnName = {'Registered'};
            app.RegTable.RowName = {};
            app.FiducialsButton = uibutton(app.RegTab, 'push');
            app.FiducialsButton.Text = 'Find fiducials for this file';
            app.UnmixTab = uitab(app.TabGroup);
            app.UnmixTab.Title = 'Unmixing';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HCRprocess

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end